classdef apneaDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        apneaDetectorWindow            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadEDFMenu                    matlab.ui.container.Menu
        DetectApneasandSleepMenu       matlab.ui.container.Menu
        LoadHumanApneaDetectionMenu    matlab.ui.container.Menu
        LoadHumanSleepScoringMenu      matlab.ui.container.Menu
        SaveAnalysisStateMenu          matlab.ui.container.Menu
        LoadPreviouslyAnalyzedEDFMenu  matlab.ui.container.Menu
        ExportDatatoMatlabStructMenu   matlab.ui.container.Menu
        ClearAppDataMenu               matlab.ui.container.Menu
        CloseMenu                      matlab.ui.container.Menu
        VersionMenu                    matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        ApneaDetectionThresholdMenu    matlab.ui.container.Menu
        xAverageBreathInterval1_5x     matlab.ui.container.Menu
        xAverageBreathIntervalMenu_2   matlab.ui.container.Menu
        UnfilteredSignalYLimitsMenu    matlab.ui.container.Menu
        incrementStd                   matlab.ui.container.Menu
        decrementStd                   matlab.ui.container.Menu
        Default4StdMenu                matlab.ui.container.Menu
        SummaryMenu                    matlab.ui.container.Menu
        OutputApneaStatsMenu           matlab.ui.container.Menu
        filteredSignalWindow           matlab.ui.control.UIAxes
        unfilteredSignalWindow         matlab.ui.control.UIAxes
        eventInspectorPlotWindow       matlab.ui.control.UIAxes
        AutoApneaTypeButtonGroup       matlab.ui.container.ButtonGroup
        SpontaneousButton              matlab.ui.control.ToggleButton
        PostSighButton                 matlab.ui.control.ToggleButton
        PostSighPlusButton             matlab.ui.control.ToggleButton
        supportingPlot1                matlab.ui.control.UIAxes
        PlotLabel                      matlab.ui.control.Label
        supportingPlot1Dropdown        matlab.ui.control.DropDown
        supportingPlot2                matlab.ui.control.UIAxes
        PlotLabel_2                    matlab.ui.control.Label
        supportingPlot2Dropdown        matlab.ui.control.DropDown
        supportingPlot3                matlab.ui.control.UIAxes
        PlotLabel_3                    matlab.ui.control.Label
        supportingPlot3Dropdown        matlab.ui.control.DropDown
        breathClusteringPlot           matlab.ui.control.UIAxes
        ManualApneaAdjustButtonGroup   matlab.ui.container.ButtonGroup
        SpontaneousButton_2            matlab.ui.control.ToggleButton
        PostSighButton_2               matlab.ui.control.ToggleButton
        PostSighPlusButton_2           matlab.ui.control.ToggleButton
        NotEventButton_2               matlab.ui.control.ToggleButton
        UnadjustedButton               matlab.ui.control.ToggleButton
        ofTextAreaLabel                matlab.ui.control.Label
        totalNumberOfApneasText        matlab.ui.control.TextArea
        recordLabelTextArea            matlab.ui.control.TextArea
        SelectPlethChannelDropDown     matlab.ui.control.DropDown
        EventSpinnerLabel              matlab.ui.control.Label
        EventSpinner                   matlab.ui.control.Spinner
        EventNotesTextAreaLabel        matlab.ui.control.Label
        EventNotesTextArea             matlab.ui.control.TextArea
        SessionNotesTextAreaLabel      matlab.ui.control.Label
        SessionNotesTextArea           matlab.ui.control.TextArea
        RawSignalButton                matlab.ui.control.StateButton
        zoomOutEventInspectorButton    matlab.ui.control.Button
        zoonInEventInspectorButton     matlab.ui.control.Button
        DetectApneasShortcut           matlab.ui.control.Button
        LoadSignalShortcut             matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        version = 'ver 0.7';
        
        stdMultiplier = 4; % The multiplier default for setting the yLimits when viewing the unfiltered signal
        defaultSignal = 1;
        filteredSignalYlimScaleFactor = 4; % The multiplier default for setting the yLimits when viewing the filtered signal
               
        % plotting cutoffs
        highCutIEI = 6;
        tidalVolCut = 3000;
        highCutAmplitudes = 15;
        
        downsampleFactor = 20; % this is important for preventing glitches due to Matlab's plotting slowness.
               
        % initialise some variables that don't have starting values
        filespec;
        header;
        targetSignal;
        breathSignal;
        std_breathSignal;
        fs;
        totalSeconds;
        totalMinutes;
        timeSignal;
        signalSize;
        filteredSignal;
        noiseIndicator;
        f1;
        output;
        whichEvent;
        eventInspectorBuffer;
        eventInspectorPeaksBuffer;
        zoomInOutFactor;
        
        amplitudes;
        peaks;
        starts;
        ends;
        ideal;
        oldIdeal;
        BPM;
        iei;
        durations;
        meanTidalVolume;
        tidalVolume;
        mistakes;
        
        potentialHypopneas;
        
        averageBreathDuration;

        theseAreMissedBreaths;
        apneaDurations;
        apneaIndex;
        howManyApneas;

        spontaneousApneaIndex;
        spontaneousApneaDurations;
        postSighApneaIndex;
        postSighApneaDurations;
        nSpontaneousApneas;
        nPostSighApneas;
        
        spontaneousStarts ;
        postSighStarts;
        apneaStarts;
        postSighPlusDurationCrit;
        postSighPlusStarts;
        howManyPostSighPlus;
        howManySpontaneous;
        howManyPostSigh;
            
        wakeStarts;
        wakeEnds;
        wakeDurations;
        
        % variables for comparing human and automated sleep
        humanScore;
        humanScoreFilesep;
        humanSleepScore;
        humanSleepScoreFilesep;
        
        % variables for comparing human and automated apnea detection
        tp_apneaScores;
        fp_apneaScores;
        fn_apneaScores;
        tn_apneaScores;
        confusionMat_apneaScores;
        
        % variables for the eventInspector event marker
        tempMarkerFiltered;
        tempMarkerCluster;
        tempMarkerEventInspector;
  
        % downsampling variables for plotting
        downsampledTime;
        downsampledBreath;
        downsampledFilteredSignal;
        downsampledIdeal;
        
        items;
        tempvalue;
        windowwindow;
        
        humanApneaLoaded;
        humanSleepLoaded;
        
    end
    
    methods (Access = public)
        
        function importFile(app)
            [ app.header,  app.breathSignal] = edfread( app.filespec, 'targetSignals', app.targetSignal); % can add 'targetSignals',targetSignal to only important relevant siganls
            app.std_breathSignal = std( app.breathSignal);
        end
                
        function plotUnfilteredSignalWindow(app)
            
            % trim down the unfiltered signal to make plotting more efficient.
            app.downsampledTime = downsample(app.timeSignal, app.downsampleFactor);
            app.downsampledBreath = downsample(app.breathSignal, app.downsampleFactor);
            
            % now plot the unfiltered Signal
            plot(app.unfilteredSignalWindow,  app.downsampledTime,  app.downsampledBreath - mean( app.breathSignal), 'k')
            app.unfilteredSignalWindow.YLim = [- app.std_breathSignal *  app.stdMultiplier,  app.std_breathSignal *  app.stdMultiplier];
            app.unfilteredSignalWindow.XLim = [0  size(app.downsampledTime, 2) / app.fs * app.downsampleFactor];


        end
    
    
        function plotFilteredSignalAndApneas(app)
            
            % downsample the filteredSignal for plotting
            app.downsampledFilteredSignal = downsample(app.filteredSignal, app.downsampleFactor);
            app.downsampledIdeal = downsample(app.output.ideal, app.downsampleFactor);
            
            % plot the filtered signal
            p1 = plot(app.filteredSignalWindow, app.downsampledTime, app.downsampledFilteredSignal, 'k');
            hold(app.filteredSignalWindow, 'on')
            
            % plot the ideal signal if you want but it's not really needed in this window at this scale. If you add this back in then you might want to adjust the legend below.
            %p2 = plot(app.filteredSignalWindow, app.downsampledTime, app.downsampledIdeal, 'c');        
            
            % plot breath amplitudes
            p3 = plot(app.filteredSignalWindow, app.output.peaks / app.fs, app.output.amplitudes, '.c', 'MarkerSize', 10);
                    
            % plot the breaths with < 60% tidal volume from the median breath, hypopneas
            p5 = plot(app.filteredSignalWindow, app.output.peaks(app.output.potentialHypopneas) / app.fs, app.output.amplitudes(app.output.potentialHypopneas), 'b.', 'MarkerSize', 4);
            
            % plot clustered Sighs
            p4 = plot(app.filteredSignalWindow, app.output.peaks(app.output.postSighApneaIndex) / app.fs, (app.output.amplitudes(app.output.postSighApneaIndex) * 0) - 4, 'ob');
            
            % adjust the x and y limits
            app.filteredSignalWindow.XLim = [0  size(app.downsampledTime, 2) / app.fs * app.downsampleFactor];
            app.filteredSignalWindow.YLim = [-6 12];
            
            % loop through all of the apneas and plot them on the graph
            for i = 1:size(app.output.apneaDurations, 2)
                temp = app.output.peaks(app.output.apneaIndex(i)):(app.output.peaks(app.output.apneaIndex(i)) + (app.output.apneaDurations(i) * app.fs));
                plot(app.filteredSignalWindow, app.timeSignal(temp), ones(length(temp), 1) * -4, '.r', 'MarkerSize', 8)
            end
            msp = plot(app.filteredSignalWindow, app.timeSignal(temp), ones(length(temp), 1) * -4, '.r', 'MarkerSize', 8); % used for legend
            
            % add the human apnea scores if the user as imported them
            if app.humanApneaLoaded
                for i = 1:size(app.humanScore, 1)
                    startTime = app.humanScore.StartTime(i);
                    endTime = startTime + app.humanScore.Duration(i);
                    temp = startTime:endTime;
                    plot(app.filteredSignalWindow, temp, ones(length(temp), 1) * -5, '.m', 'MarkerSize', 8)
                end
            end
            hapnealeg = plot(app.filteredSignalWindow, temp, ones(length(temp), 1) * -5, '.m', 'MarkerSize', 8); % used for legend

            
            %plotSleepPeriods
            for jj = 1:length(app.output.wakeStarts)
                temp = app.output.wakeStarts(jj):app.output.wakeStarts(jj)+app.output.wakeDurations(jj);
                plot(app.filteredSignalWindow, app.timeSignal(temp), ones(length(temp), 1) * -4, 'g', 'linewidth', 10)
            end
            awd = plot(app.filteredSignalWindow, app.timeSignal(temp), ones(length(temp), 1) * -4, 'g', 'linewidth', 10); % used for legend

            % plot the legend
            if app.humanApneaLoaded
                legend(app.filteredSignalWindow, [p1, p3, msp, hapnealeg, p4, p5, awd], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Human Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Wake'})
            else
                legend(app.filteredSignalWindow, [p1, p3, msp, p4, p5, awd], {'Filtered Signal', 'Reg Breaths', 'Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Wake'})
            end
            
        end
    
        function plotBreathClustering(app)
            
            % plot the regular breaths first
            temp1 = plot(app.breathClusteringPlot, app.output.iei(app.output.theseAreNormalBreaths), app.output.amplitudes(app.output.theseAreNormalBreaths(1:end)), 'c.', 'MarkerSize', 10); % these are non-apnea related breaths
            hold(app.breathClusteringPlot, 'on')
            grid(app.breathClusteringPlot, 'on')
            
            % adjust the x and y limits
            app.breathClusteringPlot.XLim = [0, app.highCutIEI];
            app.breathClusteringPlot.YLim = [0, app.highCutAmplitudes];
                
            % now plot the rest of the events
            temp2 = plot(app.breathClusteringPlot, app.output.iei(app.output.spontaneousApneaIndex), app.output.amplitudes(app.output.spontaneousApneaIndex), 'r.', 'MarkerSize', 10);
            temp3 = plot(app.breathClusteringPlot, app.output.iei(app.output.postSighApneaIndex), app.output.amplitudes(app.output.postSighApneaIndex), 'r.', 'MarkerSize', 10);
            temp4 = plot(app.breathClusteringPlot, app.output.iei(app.output.postSighApneaIndex), app.output.amplitudes(app.output.postSighApneaIndex), 'bo', 'MarkerSize', 6);
            
            % plot the legend
            legend(app.breathClusteringPlot, [temp1, temp2, temp4], {'Reg Breaths', 'Apneas', 'Post Sigh Apnea'})


        end
    
        function plotEventInspector(app)            
            
            tempEndDistMult = 2;
            % set up a few variables
            whichEventIndex = app.output.apneaIndex(app.whichEvent);
            indexForPeaks = whichEventIndex - app.eventInspectorPeaksBuffer :whichEventIndex + app.eventInspectorPeaksBuffer;
            apneaPeakStart = app.output.peaks(whichEventIndex) / app.fs;
            apneaStartInd = find(app.timeSignal == floor(apneaPeakStart));
            if (apneaStartInd - app.eventInspectorBuffer) <= 0
                plotStartInd = 1;
            else
                plotStartInd = apneaStartInd - app.eventInspectorBuffer;
            end
            if plotStartInd + (app.eventInspectorBuffer * tempEndDistMult) > length(app.timeSignal)
                plotEndInd = length(app.timeSignal);
            else
                plotEndInd = plotStartInd + (app.eventInspectorBuffer * tempEndDistMult);
            end
            
            % plot the filtered signal
            p1 = plot(app.eventInspectorPlotWindow, app.downsampledTime(floor((plotStartInd:plotEndInd) ./ app.downsampleFactor)), app.downsampledFilteredSignal(floor((plotStartInd:plotEndInd) ./ app.downsampleFactor)), 'k');
            hold(app.eventInspectorPlotWindow, "on")
           
            % plot the raw signal in the event inspector if requested
            if app.RawSignalButton.Value
                temp = app.breathSignal * 2;
                scaledUnfilteredSignal = (temp - mean(temp)) ./ std(temp, 1);
                scaledUnfilteredSignal = downsample(scaledUnfilteredSignal, app.downsampleFactor);
                plot(app.eventInspectorPlotWindow, app.downsampledTime(floor((plotStartInd:plotEndInd) ./ app.downsampleFactor)), scaledUnfilteredSignal(floor((plotStartInd:plotEndInd) ./ app.downsampleFactor)), 'm');
            end

            % plot the ideal signal
            p2 = plot(app.eventInspectorPlotWindow, app.downsampledTime(floor((plotStartInd:plotEndInd) ./ app.downsampleFactor)), app.downsampledIdeal(floor((plotStartInd:plotEndInd) ./ app.downsampleFactor)), 'c');            

            % plot breath amplitudes
            p3 = plot(app.eventInspectorPlotWindow, app.output.peaks(indexForPeaks) / app.fs, app.output.amplitudes(indexForPeaks), '.c', 'MarkerSize', 15);
            
            % plot the breaths with < 60% tidal volume from the median breath, hypopneas
            p5 = plot(app.eventInspectorPlotWindow, app.output.peaks(intersect(indexForPeaks, find(app.output.potentialHypopneas))) / app.fs , app.output.amplitudes(intersect(indexForPeaks, find(app.output.potentialHypopneas))), 'b.', 'MarkerSize', 15);
            
            % plot clustered Sighs
            p4 = plot(app.eventInspectorPlotWindow, app.output.peaks(intersect(indexForPeaks, app.output.postSighApneaIndex)) / app.fs, app.output.amplitudes(intersect(indexForPeaks, app.output.postSighApneaIndex)), 'ob', 'MarkerSize', 10);
                        
            % set the x and y limits for the plot.
            app.eventInspectorPlotWindow.XLim = [app.timeSignal(plotStartInd) app.timeSignal(plotEndInd)];
            app.eventInspectorPlotWindow.YLim = [-6 10];
            
            % plot apneas in the range of the eventInspector
            [tempIntersect, ~, listOfWhichApneas] = intersect(indexForPeaks, app.output.apneaIndex); % index for peaks goes back - 20 breaths from the event in question
            whichPeaks = app.output.peaks(tempIntersect) / app.fs;
            tempDurations = app.output.apneaDurations(listOfWhichApneas); % these are still a smidge short in the plotting window and I don't know why..

            for i = 1:length(tempIntersect)
                quantitything = (whichPeaks(i):whichPeaks(i)+tempDurations(i));
                plot(app.eventInspectorPlotWindow, quantitything, ones(length(quantitything), 1) * 5, 'r', 'linewidth', 15)
            end
            
            % this still needs to be reduced to only display events within event inspector but I'm just putting this here to see if it works.
            if app.humanApneaLoaded
                for i = 1:size(app.humanScore, 1)
                    startTime = app.humanScore.StartTime(i);
                    endTime = startTime + app.humanScore.Duration(i);
                    temp = startTime:endTime;
                    
                    plot(app.eventInspectorPlotWindow, temp, ones(length(temp), 1) * 6, 'm', 'linewidth', 15)
                end
            end
        
            % now mark where this event occurs on the main plot window and in the clustering batch.
            app.tempMarkerFiltered = plot(app.filteredSignalWindow, app.timeSignal(app.output.peaks(app.output.apneaIndex(app.whichEvent))), -4, 'or', 'linewidth', 2, 'markersize', 25);
            app.tempMarkerCluster = plot(app.breathClusteringPlot, app.output.iei(app.output.apneaIndex(app.whichEvent)) , app.output.amplitudes(app.output.apneaIndex(app.whichEvent)), 'or', 'linewidth', 2, 'markersize', 25); 
            app.tempMarkerEventInspector =  plot(app.eventInspectorPlotWindow, app.output.peaks(intersect(indexForPeaks, app.output.apneaIndex(app.whichEvent))) / app.fs, 9, 'vr', 'linewidth', 2, 'markersize', 25);
            
            % add the apnea type labeling 
            if app.output.typeOfApnea(app.whichEvent) == 1
                app.SpontaneousButton.Value = true;
            elseif app.output.typeOfApnea(app.whichEvent) == 2
                app.PostSighButton.Value = true;
            elseif app.output.typeOfApnea(app.whichEvent) == 3
                app.PostSighPlusButton.Value = true;
            end                
            
            if app.output.manualTypeOfApnea(app.whichEvent) == 0
                app.UnadjustedButton.Value = true;
            elseif app.output.manualTypeOfApnea(app.whichEvent) == 1
                app.SpontaneousButton_2.Value = true;
            elseif app.output.manualTypeOfApnea(app.whichEvent) == 2
                app.PostSighButton_2.Value = true;
            elseif app.output.manualTypeOfApnea(app.whichEvent) == 3
                app.PostSighPlusButton_2.Value = true;
            elseif app.output.manualTypeOfApnea(app.whichEvent) == 4
                app.NotEventButton_2.Value = true;
            end 
                        
            % load notes data
            app.EventNotesTextArea.Value = app.output.apneaNotes(app.whichEvent);
        
             
        end
    
        function plotSummaryDataInSupportWindow(app, windowNumber)
        
            dropDownValue = app.items(1);
            
            % choose that dynamically allows window selection and inactivates interactions with the plots
            if windowNumber == 1
                temp = app.supportingPlot1;
                app.supportingPlot1Dropdown.Value = dropDownValue;
            elseif windowNumber == 2
                temp = app.supportingPlot2;
                app.supportingPlot2Dropdown.Value = dropDownValue;
            else
                temp = app.supportingPlot3;
                app.supportingPlot3Dropdown.Value = dropDownValue;
  
            end
                        
            % plot numerous text variables of interest
            text(temp, 0,0.9, strcat("Apnea Threshold (s): ", num2str(app.output.apneaThreshold)));
            text(temp, 0,0.8, strcat("n Spontaneous Apneas: ", num2str(app.output.nSpontaneousApneas)));
            text(temp, 0,0.7, strcat("S Apneas/Hour Sleep: ", num2str(round(app.output.nSpontaneousApneas / app.output.totalHoursSleeping, 2))));
            text(temp, 0,0.6, strcat("n Post Sigh Apneas: ", num2str(app.output.nPostSighApneas)));
            text(temp, 0,0.5, strcat("PS Apneas/Hour Sleep: ", num2str(round(app.output.nPostSighApneas / app.output.totalHoursSleeping, 2))));
            text(temp, 0,0.4, strcat("n Post Sigh Plus Apneas: ", num2str(app.output.nPostSighPlus)));
            text(temp, 0,0.3, strcat("PSP Apneas/Hour Sleep: ", num2str(round(app.output.nPostSighPlus / app.output.totalHoursSleeping, 2))));
            text(temp, 0,0.2, strcat("Total Sleep Hours: ", num2str(app.output.totalHoursSleeping)));
            axis(temp, 'off')
         
            
        end
        
         function plotIEIHistInSupportWindow(app, windowNumber)
        
            dropDownValue = app.items(2);
            
            % choose that dynamically allows window selection and inactivates interactions with the plots
            if windowNumber == 1
                temp = app.supportingPlot1;
                app.supportingPlot1Dropdown.Value = dropDownValue;
            elseif windowNumber == 2
                temp = app.supportingPlot2;
                app.supportingPlot2Dropdown.Value = dropDownValue;
            else
                temp = app.supportingPlot3;
                app.supportingPlot3Dropdown.Value = dropDownValue;
            end
            
            %
            axis(temp, 'on')
            tempPlotData = histogram(temp, app.output.iei);
            xlabel(temp, 'Time (s)')
            ylabel(temp, 'Frequency Counts')
            xlim(temp, [0 2])
            hold(temp, 'on')
            
            plot(temp, app.output.apneaThreshold / 2, max(tempPlotData.Values) + max(tempPlotData.Values) * .10, 'vr', 'MarkerSize', 10)
            
         end
         
         function plotAmplitudeHistInSupportWindow(app, windowNumber)
        
            dropDownValue = app.items(3);
            
            % choose that dynamically allows window selection and inactivates interactions with the plots
            if windowNumber == 1
                temp = app.supportingPlot1;
                app.supportingPlot1Dropdown.Value = dropDownValue;
            elseif windowNumber == 2
                temp = app.supportingPlot2;
                app.supportingPlot2Dropdown.Value = dropDownValue;
            else
                temp = app.supportingPlot3;
                app.supportingPlot3Dropdown.Value = dropDownValue;
            end
            
            %
            axis(temp, 'on')
            tempPlotData = histogram(temp, app.output.amplitudes);
            xlabel(temp, 'Norm. Pleth Amplitude')
            ylabel(temp, 'Frequency Counts')
            xlim(temp, [0 app.highCutAmplitudes])
            
            
         end
         

        function plotApneaComparisonInSupportWindow(app, windowNumber)
        
            if app.humanApneaLoaded == 1
                    
                % choose that dynamically allows window selection and inactivates interactions with the plots
                if windowNumber == 1
                    temp = app.supportingPlot1;
                    app.supportingPlot1Dropdown.Value = app.items(4);
                elseif windowNumber == 2
                    temp = app.supportingPlot2;
                    app.supportingPlot2Dropdown.Value = app.items(4);
                else
                    temp = app.supportingPlot3;
                    app.supportingPlot3Dropdown.Value = app.items(4);
                end
            
                
                % plot numerous text variables of interest
                text(temp, 0,0.8, strcat("True Positives: ", num2str(app.confusionMat_apneaScores(4))));
                text(temp, 0,0.7, strcat("False Positives: ", num2str(app.confusionMat_apneaScores(3))));
                text(temp, 0,0.6, strcat("False Negatives: ", num2str(app.confusionMat_apneaScores(2)))); % not sure if 2 and 3 should be switched.
                text(temp, 0,0.5, strcat("True Negatives: ", num2str(app.confusionMat_apneaScores(1))));
                axis(temp, 'off')
                
            end
            
        end
        
        function plotSleepComparisonInSupportWindow(app)
            if app.humanSleepLoaded == 1
                % this needs to be filled in when sleep Comparison becomes available
            end
        end
        
        function plottingForDropdownMenus(app, tempvalue, windowwindow)
            if tempvalue == 1
                plotSummaryDataInSupportWindow(app, windowwindow)
            elseif tempvalue == 2
                plotIEIHistInSupportWindow(app, windowwindow)
            elseif tempvalue == 3
                plotAmplitudeHistInSupportWindow(app, windowwindow)
            elseif tempvalue == 4
                plotApneaComparisonInSupportWindow(app, windowwindow)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0,'DefaultLegendAutoUpdate','off')
            
            % make plot interactions unavailable
            app.unfilteredSignalWindow.Toolbar.Visible = 'off';
            app.unfilteredSignalWindow.Interactions = [];
            
            app.filteredSignalWindow.Toolbar.Visible = 'off';
            app.filteredSignalWindow.Interactions = []; 
            
            app.breathClusteringPlot.Toolbar.Visible = 'off';
            app.breathClusteringPlot.Interactions = [];
            
            app.eventInspectorPlotWindow.Toolbar.Visible = 'off';
            app.eventInspectorPlotWindow.Interactions = [];
            
            app.supportingPlot1.Toolbar.Visible = 'off';
            app.supportingPlot1.Interactions = []; 
            
            app.supportingPlot2.Toolbar.Visible = 'off';
            app.supportingPlot2.Interactions = [];             
            
            app.supportingPlot2.Toolbar.Visible = 'off';
            app.supportingPlot2.Interactions = []; 

        end

        % Callback function: LoadEDFMenu, LoadSignalShortcut
        function openFile(app, event)
            
            
            app.apneaDetectorWindow.Visible = 'off'; % these are a crude workaround to a matlab glitch on mac. Otherwise uigetfile opens up behind app window.
            [file,  path] = uigetfile('*.edf', 'Pick an EDF file');
            app.apneaDetectorWindow.Visible = 'on'; 

            app.filespec = strcat( path,  file);
            
            % import the file header
            [ app.header] = edfread( app.filespec);
            app.SelectPlethChannelDropDown.Items =  app.header.label;
            app.SelectPlethChannelDropDown.Value =  app.header.label(app.defaultSignal);
            app.targetSignal = app.SelectPlethChannelDropDown.Value;

            % import the file, this should be an EDF file
            importFile(app)
            
            % set the sample rate
            app.fs =  app.header.frequency(1); % this sample rate gives shows 120 BPM for a rat which is about what we are looking for and matches the timing from the excel files that Jonathan provided.
            
            % establish the time signal
            app.totalSeconds = length( app.breathSignal) /  app.fs;
            app.totalMinutes =  app.totalSeconds / 60;
            app.timeSignal = 0:( app.totalSeconds/length( app.breathSignal)): app.totalSeconds;
            app.timeSignal =  app.timeSignal(2:end);

            % possibly open up a popup here so that users can select the correct channel?
            
            
            % plot the original signal
            plotUnfilteredSignalWindow(app)
                  
            % put a label for the record in the bottom panel
            app.recordLabelTextArea.Value =  app.filespec;
            
            app.SelectPlethChannelDropDown.Visible = 'on';
            app.DetectApneasShortcut.Visible = 'on';
            app.LoadSignalShortcut.Visible = 'off';
            

        end

        % Value changed function: SelectPlethChannelDropDown
        function SelectPlethChannelDropDownValueChanged(app, event)
            app.targetSignal = app.SelectPlethChannelDropDown.Value;
            importFile(app)
            plotUnfilteredSignalWindow(app)
            %plotFilteredSingal(app)
            
        end

        % Menu selected function: Default4StdMenu
        function Default4StdMenuSelected(app, event)
            app.stdMultiplier = 4;
            plotUnfilteredSignalWindow(app)
        end

        % Menu selected function: incrementStd
        function incrementStdSelected(app, event)
            app.stdMultiplier =  app.stdMultiplier + 1;
            plotUnfilteredSignalWindow(app)
        end

        % Menu selected function: decrementStd
        function decrementStdMenuSelected(app, event)
            app.stdMultiplier =  app.stdMultiplier - 1;
            plotUnfilteredSignalWindow(app)
        end

        % Callback function: DetectApneasShortcut, 
        % DetectApneasandSleepMenu
        function DetectApneasandSleepMenuSelected(app, event)
            
            
            % first clear the plot data
            cla(app.filteredSignalWindow)
            cla(app.eventInspectorPlotWindow)
            cla(app.breathClusteringPlot)
            cla(app.supportingPlot1)
            cla(app.supportingPlot2)
            cla(app.supportingPlot3)
            
            
            % populate list for supporting plot dropdowns
            app.items = {'Summary Data', 'Breath IEI Hist', 'Breath Amp Hist', 'ApneaScore Comp'}; % add this eventually 'SleepScore Comp'
            app.supportingPlot1Dropdown.Items = app.items;
            app.supportingPlot2Dropdown.Items = app.items;
            app.supportingPlot3Dropdown.Items = app.items;
            
            
            d = uiprogressdlg(app.apneaDetectorWindow,'Title','Detecting Breaths and Apneas','Indeterminate','on', 'Cancelable','on');

            tic

            % tell user to wait
            %text(app.filteredSignalWindow, .2, .5, "Please Wait While Breaths and Apneas are Detected.", 'FontSize', 25, 'Units','normalized')

            app.filteredSignal = filterAndNormalizePlethSignal(app.breathSignal, app.fs);
            
            app.output = detectBreathsApneasAndSleepFromPleth(app.filteredSignal, app.fs);
                        
            
            % initialize manual apnea change list
            app.output.manualTypeOfApnea = app.output.typeOfApnea * 0; % sets everything to zero for the manual type which means, 'Unadjusted'
            
            
            plotFilteredSignalAndApneas(app)
            
            plotBreathClustering(app)
            
            plotSummaryDataInSupportWindow(app, 1)
            
            plotIEIHistInSupportWindow(app, 2)
            
            plotAmplitudeHistInSupportWindow(app, 3)
           
            
            % set some variables for the event Inspector
            app.eventInspectorBuffer = app.fs * 8;
            app.eventInspectorPeaksBuffer = 80;
            app.whichEvent = 1;
            app.zoomInOutFactor = 3;
            
            % initialize event notes space
            for i = 1:app.output.howManyApneas
                app.output.apneaNotes{i} = '';
            end
            
            plotEventInspector(app)
            
            app.totalNumberOfApneasText.Value = num2str(app.output.howManyApneas);
            app.EventSpinner.Value = app.whichEvent;
            app.EventSpinner.Limits = [1 app.output.howManyApneas];
    
            % enable some menu items now that we've detected apneas
            app.LoadHumanApneaDetectionMenu.Enable = 'on';
            app.LoadHumanSleepScoringMenu.Enable = 'off'; % this is still unavailable
            app.SaveAnalysisStateMenu.Enable = 'on';
            app.ClearAppDataMenu.Enable = 'on';
            app.ExportDatatoMatlabStructMenu.Enable = 'on';
            
            toc
            
            close(d)
            
        end

        % Menu selected function: LoadHumanApneaDetectionMenu
        function LoadHumanApneaDetectionMenuSelected(app, event)
            
            app.apneaDetectorWindow.Visible = 'off'; % these are a crude workaround to a matlab glitch on mac. Otherwise uigetfile opens up behind app window.
            [file,  path] = uigetfile('*.csv', 'Pick a Sleep Score File');
            app.apneaDetectorWindow.Visible = 'on';

            d = uiprogressdlg(app.apneaDetectorWindow,'Title','Loading Human Scored Apneas','Indeterminate', 'on', 'Cancelable','on');

            % import the human sleep score csv
            app.humanScoreFilesep = strcat( path,  file);
            app.humanScore = importfile_humanApneaScores(app.humanScoreFilesep);
            app.humanScore = app.humanScore(app.humanScore.Duration >= app.output.apneaThreshold, :); % only scores above the same apnea threshold as the computer set.
            
            % compare automated and human apneas
            compareApneaScores(app)

            % delete the old markers for specified events.
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);
            delete(app.tempMarkerEventInspector);

            % clear the plot window and replot
            cla(app.eventInspectorPlotWindow)
            cla(app.filteredSignalWindow)
                        
            % make a tag so we can tell the program that these data were imported
            app.humanApneaLoaded = 1;
            
            % plot everything
            plotFilteredSignalAndApneas(app)
            plotEventInspector(app)
            
            cla(app.supportingPlot3)            
            plotApneaComparisonInSupportWindow(app, 3)
            app.supportingPlot3Dropdown.Value = app.items(4);


            close(d)
        end

        % Value changed function: EventSpinner
        function EventSpinnerValueChanged(app, event)
            
            app.whichEvent = app.EventSpinner.Value;
            
            % delete the old markers for specified events.
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);
            delete(app.tempMarkerEventInspector);

            % clear the plot window and replot
            cla(app.eventInspectorPlotWindow)
            plotEventInspector(app);
            
        end

        % Menu selected function: SaveAnalysisStateMenu
        function SaveAnalysisStateMenuSelected(app, event)
            cd(fileparts(app.filespec)); % change directory to current working file
            uisave('app') % prompt user to save file
            
            
        end

        % Menu selected function: LoadPreviouslyAnalyzedEDFMenu
        function LoadPreviouslyAnalyzedEDFMenuSelected(app, event)
            
            app.apneaDetectorWindow.Visible = 'off';
            [file,  path] = uigetfile('*.mat', 'Pick a Scored Apnea Mat File.');
            app.apneaDetectorWindow.Visible = 'on';
             
            cla(app.filteredSignalWindow)
            cla(app.unfilteredSignalWindow)
            cla(app.breathClusteringPlot)
            
            load(strcat(path, file), '-mat');

            
            plotFilteredSignalAndApneas(app)
            plotUnfilteredSignalWindow(app)
            plotBreathClustering(app)
        end

        % Value changed function: EventNotesTextArea
        function EventNotesTextAreaValueChanged(app, event)
            app.output.apneaNotes(app.whichEvent) = app.EventNotesTextArea.Value;
            
        end

        % Menu selected function: ExportDatatoMatlabStructMenu
        function ExportDatatoMatlabStructMenuSelected(app, event)
            app.output.version = app.version;
            app.output.dateProcessed = date;
            apneaDetectorOutput = app.output;
            uisave('apneaDetectorOutput');
            
        end

        % Value changed function: SessionNotesTextArea
        function SessionNotesTextAreaValueChanged(app, event)
            app.output.sessionNotes = app.SessionNotesTextArea.Value;
            
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            closereq
        end

        % Value changed function: supportingPlot1Dropdown
        function supportingPlot1DropdownValueChanged(app, event)
            cla(app.supportingPlot1)
            app.tempvalue = app.supportingPlot1Dropdown.Value;
            app.tempvalue = find(strcmp(app.items, app.tempvalue));
            app.windowwindow = 1;
            plottingForDropdownMenus(app, app.tempvalue, app.windowwindow)
        end

        % Value changed function: supportingPlot2Dropdown
        function supportingPlot2DropdownValueChanged(app, event)
            cla(app.supportingPlot2)
            app.tempvalue = app.supportingPlot2Dropdown.Value;
            app.tempvalue = find(strcmp(app.items, app.tempvalue));
            app.windowwindow = 2;
            plottingForDropdownMenus(app, app.tempvalue, app.windowwindow)
        end

        % Value changed function: supportingPlot3Dropdown
        function supportingPlot3DropdownValueChanged(app, event)
            cla(app.supportingPlot3)
            app.tempvalue = app.supportingPlot3Dropdown.Value;
            app.tempvalue = find(strcmp(app.items, app.tempvalue));
            app.windowwindow = 3;
            plottingForDropdownMenus(app, app.tempvalue, app.windowwindow)
        end

        % Button pushed function: zoomOutEventInspectorButton
        function zoomOutEventInspectorButtonPushed(app, event)
            app.eventInspectorBuffer = app.eventInspectorBuffer + ( app.fs * app.zoomInOutFactor);
            app.eventInspectorPeaksBuffer = app.eventInspectorPeaksBuffer + (app.zoomInOutFactor * 10);
            
            % delete the old markers for specified events.
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);
            delete(app.tempMarkerEventInspector);

            % clear the plot window and replot
            cla(app.eventInspectorPlotWindow)
            plotEventInspector(app);
        end

        % Button pushed function: zoonInEventInspectorButton
        function zoonInEventInspectorButtonPushed(app, event)
            if app.eventInspectorBuffer > app.fs * 5
                
                % delete the old markers for specified events.
                delete(app.tempMarkerFiltered);
                delete(app.tempMarkerCluster);
                delete(app.tempMarkerEventInspector);
    
                % clear the plot window and replot
                cla(app.eventInspectorPlotWindow)
                plotEventInspector(app);
            
                app.eventInspectorBuffer = app.eventInspectorBuffer - ( app.fs * app.zoomInOutFactor);
                app.eventInspectorPeaksBuffer = app.eventInspectorPeaksBuffer - (app.zoomInOutFactor * 10);
            end
        end

        % Callback function
        function defButtonPushed(app, event)
            app.eventInspectorBuffer = app.fs * 8;
            app.eventInspectorPeaksBuffer = 80;
            
            % delete the old markers for specified events.
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);
            delete(app.tempMarkerEventInspector);

            % clear the plot window and replot
            cla(app.eventInspectorPlotWindow)
            plotEventInspector(app);
            
        end

        % Value changed function: RawSignalButton
        function RawSignalButtonValueChanged(app, event)
            % delete the old markers for specified events.
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);
            delete(app.tempMarkerEventInspector);

            % clear the plot window and replot
            cla(app.eventInspectorPlotWindow)
            plotEventInspector(app);
        end

        % Selection changed function: ManualApneaAdjustButtonGroup
        function ManualApneaAdjustButtonGroupSelectionChanged(app, event)
            temp = app.ManualApneaAdjustButtonGroup.SelectedObject;
            
            if strcmp(temp.Text, 'Unadjusted')
                app.output.manualTypeOfApnea(app.whichEvent) = 0;
            elseif strcmp(temp.Text, 'Spontaneous')
                app.output.manualTypeOfApnea(app.whichEvent) = 1;
            elseif strcmp(temp.Text, 'Post Sigh')
                app.output.manualTypeOfApnea(app.whichEvent) = 2;
            elseif strcmp(temp.Text, 'Post Sigh Plus')
                app.output.manualTypeOfApnea(app.whichEvent) = 3;
            elseif strcmp(temp.Text, 'Not Event')
                app.output.manualTypeOfApnea(app.whichEvent) = 4;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.apneaDetectorWindow = uifigure('Visible', 'off');
            app.apneaDetectorWindow.Name = 'apneaDetector';
            app.FileMenu = uimenu(app.apneaDetectorWindow);
            app.FileMenu.Text = 'File';
            app.LoadEDFMenu = uimenu(app.FileMenu);
            app.LoadEDFMenu.MenuSelectedFcn = createCallbackFcn(app, @openFile, true);
            app.LoadEDFMenu.Text = 'Load EDF';
            app.DetectApneasandSleepMenu = uimenu(app.FileMenu);
            app.DetectApneasandSleepMenu.MenuSelectedFcn = createCallbackFcn(app, @DetectApneasandSleepMenuSelected, true);
            app.DetectApneasandSleepMenu.Text = 'Detect Apneas and Sleep';
            app.LoadHumanApneaDetectionMenu = uimenu(app.FileMenu);
            app.LoadHumanApneaDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadHumanApneaDetectionMenuSelected, true);
            app.LoadHumanApneaDetectionMenu.Enable = 'off';
            app.LoadHumanApneaDetectionMenu.Text = 'Load Human Apnea Detection';
            app.LoadHumanSleepScoringMenu = uimenu(app.FileMenu);
            app.LoadHumanSleepScoringMenu.Enable = 'off';
            app.LoadHumanSleepScoringMenu.Text = 'Load Human Sleep Scoring';
            app.SaveAnalysisStateMenu = uimenu(app.FileMenu);
            app.SaveAnalysisStateMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAnalysisStateMenuSelected, true);
            app.SaveAnalysisStateMenu.Visible = 'off';
            app.SaveAnalysisStateMenu.Enable = 'off';
            app.SaveAnalysisStateMenu.Text = 'Save Analysis State';
            app.LoadPreviouslyAnalyzedEDFMenu = uimenu(app.FileMenu);
            app.LoadPreviouslyAnalyzedEDFMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPreviouslyAnalyzedEDFMenuSelected, true);
            app.LoadPreviouslyAnalyzedEDFMenu.Visible = 'off';
            app.LoadPreviouslyAnalyzedEDFMenu.Text = 'Load Previously Analyzed EDF';
            app.ExportDatatoMatlabStructMenu = uimenu(app.FileMenu);
            app.ExportDatatoMatlabStructMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatoMatlabStructMenuSelected, true);
            app.ExportDatatoMatlabStructMenu.Enable = 'off';
            app.ExportDatatoMatlabStructMenu.Text = 'Export Data to Matlab Struct';
            app.ClearAppDataMenu = uimenu(app.FileMenu);
            app.ClearAppDataMenu.Enable = 'off';
            app.ClearAppDataMenu.Text = 'Clear App Data';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.VersionMenu = uimenu(app.FileMenu);
            app.VersionMenu.Enable = 'off';
            app.VersionMenu.Text = 'Version 0.7';
            app.SettingsMenu = uimenu(app.apneaDetectorWindow);
            app.SettingsMenu.Text = 'Settings';
            app.ApneaDetectionThresholdMenu = uimenu(app.SettingsMenu);
            app.ApneaDetectionThresholdMenu.Text = 'Apnea Detection Threshold';
            app.xAverageBreathInterval1_5x = uimenu(app.ApneaDetectionThresholdMenu);
            app.xAverageBreathInterval1_5x.Text = '1.5x Average Breath Interval';
            app.xAverageBreathIntervalMenu_2 = uimenu(app.ApneaDetectionThresholdMenu);
            app.xAverageBreathIntervalMenu_2.Text = '2x Average Breath Interval';
            app.UnfilteredSignalYLimitsMenu = uimenu(app.SettingsMenu);
            app.UnfilteredSignalYLimitsMenu.Text = 'Unfiltered Signal Y Limits';
            app.incrementStd = uimenu(app.UnfilteredSignalYLimitsMenu);
            app.incrementStd.MenuSelectedFcn = createCallbackFcn(app, @incrementStdSelected, true);
            app.incrementStd.Text = '+1 Std';
            app.decrementStd = uimenu(app.UnfilteredSignalYLimitsMenu);
            app.decrementStd.MenuSelectedFcn = createCallbackFcn(app, @decrementStdMenuSelected, true);
            app.decrementStd.Text = '-1 Std';
            app.Default4StdMenu = uimenu(app.UnfilteredSignalYLimitsMenu);
            app.Default4StdMenu.MenuSelectedFcn = createCallbackFcn(app, @Default4StdMenuSelected, true);
            app.Default4StdMenu.Text = 'Default (4 Std)';
            app.SummaryMenu = uimenu(app.apneaDetectorWindow);
            app.SummaryMenu.Visible = 'off';
            app.SummaryMenu.Enable = 'off';
            app.SummaryMenu.Text = 'Summary';
            app.OutputApneaStatsMenu = uimenu(app.SummaryMenu);
            app.OutputApneaStatsMenu.Text = 'Output Apnea Stats';
            app.filteredSignalWindow = uiaxes(app.apneaDetectorWindow);
            title(app.filteredSignalWindow, 'Filtered Signal and Apnea Detection')
            xlabel(app.filteredSignalWindow, 'Time (s)')
            ylabel(app.filteredSignalWindow, 'Norm. Pleth Amplitude')
            app.unfilteredSignalWindow = uiaxes(app.apneaDetectorWindow);
            title(app.unfilteredSignalWindow, 'Unfiltered Signal')
            xlabel(app.unfilteredSignalWindow, 'Time (s)')
            ylabel(app.unfilteredSignalWindow, 'Norm. Pleth Amp.')
            app.eventInspectorPlotWindow = uiaxes(app.apneaDetectorWindow);
            title(app.eventInspectorPlotWindow, 'Event Inspector')
            xlabel(app.eventInspectorPlotWindow, 'Time (s)')
            ylabel(app.eventInspectorPlotWindow, 'Norm. Pleth Amlitdue')
            app.AutoApneaTypeButtonGroup = uibuttongroup(app.apneaDetectorWindow);
            app.AutoApneaTypeButtonGroup.Title = 'Auto Apnea Type';
            app.SpontaneousButton = uitogglebutton(app.AutoApneaTypeButtonGroup);
            app.SpontaneousButton.Text = 'Spontaneous';
            app.SpontaneousButton.Value = true;
            app.PostSighButton = uitogglebutton(app.AutoApneaTypeButtonGroup);
            app.PostSighButton.Text = 'Post Sigh';
            app.PostSighPlusButton = uitogglebutton(app.AutoApneaTypeButtonGroup);
            app.PostSighPlusButton.Text = 'Post Sigh Plus';
            app.supportingPlot1 = uiaxes(app.apneaDetectorWindow);
            title(app.supportingPlot1, '')
            xlabel(app.supportingPlot1, '')
            ylabel(app.supportingPlot1, '')
            app.PlotLabel = uilabel(app.apneaDetectorWindow);
            app.PlotLabel.Text = 'Plot:';
            app.supportingPlot1Dropdown = uidropdown(app.apneaDetectorWindow);
            app.supportingPlot1Dropdown.ValueChangedFcn = createCallbackFcn(app, @supportingPlot1DropdownValueChanged, true);
            app.supportingPlot2 = uiaxes(app.apneaDetectorWindow);
            title(app.supportingPlot2, '')
            xlabel(app.supportingPlot2, '')
            ylabel(app.supportingPlot2, '')
            app.PlotLabel_2 = uilabel(app.apneaDetectorWindow);
            app.PlotLabel_2.Text = 'Plot:';
            app.supportingPlot2Dropdown = uidropdown(app.apneaDetectorWindow);
            app.supportingPlot2Dropdown.ValueChangedFcn = createCallbackFcn(app, @supportingPlot2DropdownValueChanged, true);
            app.supportingPlot3 = uiaxes(app.apneaDetectorWindow);
            title(app.supportingPlot3, '')
            xlabel(app.supportingPlot3, '')
            ylabel(app.supportingPlot3, '')
            app.PlotLabel_3 = uilabel(app.apneaDetectorWindow);
            app.PlotLabel_3.Text = 'Plot:';
            app.supportingPlot3Dropdown = uidropdown(app.apneaDetectorWindow);
            app.supportingPlot3Dropdown.ValueChangedFcn = createCallbackFcn(app, @supportingPlot3DropdownValueChanged, true);
            app.breathClusteringPlot = uiaxes(app.apneaDetectorWindow);
            title(app.breathClusteringPlot, 'Breath Clustering')
            xlabel(app.breathClusteringPlot, 'Inter-event Interval (s)')
            ylabel(app.breathClusteringPlot, 'Breath Amplitdues')
            app.ManualApneaAdjustButtonGroup = uibuttongroup(app.apneaDetectorWindow);
            app.ManualApneaAdjustButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ManualApneaAdjustButtonGroupSelectionChanged, true);
            app.ManualApneaAdjustButtonGroup.Title = 'Manual Apnea Adjust';
            app.SpontaneousButton_2 = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.SpontaneousButton_2.Text = 'Spontaneous';
            app.SpontaneousButton_2.Value = true;
            app.PostSighButton_2 = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.PostSighButton_2.Text = 'Post Sigh';
            app.PostSighPlusButton_2 = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.PostSighPlusButton_2.Text = 'Post Sigh Plus';
            app.NotEventButton_2 = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.NotEventButton_2.Text = 'Not Event';
            app.UnadjustedButton = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.UnadjustedButton.Text = 'Unadjusted';
            app.ofTextAreaLabel = uilabel(app.apneaDetectorWindow);
            app.ofTextAreaLabel.Text = 'of';
            app.totalNumberOfApneasText = uitextarea(app.apneaDetectorWindow);
            app.totalNumberOfApneasText.Editable = 'off';
            app.recordLabelTextArea = uitextarea(app.apneaDetectorWindow);
            app.SelectPlethChannelDropDown = uidropdown(app.apneaDetectorWindow);
            app.SelectPlethChannelDropDown.Items = {'Ch 1', 'Ch 2', 'Ch 3'};
            app.SelectPlethChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectPlethChannelDropDownValueChanged, true);
            app.SelectPlethChannelDropDown.Visible = 'off';
            app.SelectPlethChannelDropDown.Value = 'Ch 1';
            app.EventSpinnerLabel = uilabel(app.apneaDetectorWindow);
            app.EventSpinnerLabel.Text = 'Event #';
            app.EventSpinner = uispinner(app.apneaDetectorWindow);
            app.EventSpinner.ValueChangedFcn = createCallbackFcn(app, @EventSpinnerValueChanged, true);
            app.EventNotesTextAreaLabel = uilabel(app.apneaDetectorWindow);
            app.EventNotesTextAreaLabel.Text = 'Event Notes';
            app.EventNotesTextArea = uitextarea(app.apneaDetectorWindow);
            app.EventNotesTextArea.ValueChangedFcn = createCallbackFcn(app, @EventNotesTextAreaValueChanged, true);
            app.SessionNotesTextAreaLabel = uilabel(app.apneaDetectorWindow);
            app.SessionNotesTextAreaLabel.Text = 'Session Notes';
            app.SessionNotesTextArea = uitextarea(app.apneaDetectorWindow);
            app.SessionNotesTextArea.ValueChangedFcn = createCallbackFcn(app, @SessionNotesTextAreaValueChanged, true);
            app.RawSignalButton = uibutton(app.apneaDetectorWindow, 'state');
            app.RawSignalButton.ValueChangedFcn = createCallbackFcn(app, @RawSignalButtonValueChanged, true);
            app.RawSignalButton.Text = 'Raw Signal';
            app.zoomOutEventInspectorButton = uibutton(app.apneaDetectorWindow, 'push');
            app.zoomOutEventInspectorButton.ButtonPushedFcn = createCallbackFcn(app, @zoomOutEventInspectorButtonPushed, true);
            app.zoomOutEventInspectorButton.Text = '-';
            app.zoonInEventInspectorButton = uibutton(app.apneaDetectorWindow, 'push');
            app.zoonInEventInspectorButton.ButtonPushedFcn = createCallbackFcn(app, @zoonInEventInspectorButtonPushed, true);
            app.zoonInEventInspectorButton.Text = '+';
            app.DetectApneasShortcut = uibutton(app.apneaDetectorWindow, 'push');
            app.DetectApneasShortcut.ButtonPushedFcn = createCallbackFcn(app, @DetectApneasandSleepMenuSelected, true);
            app.DetectApneasShortcut.Visible = 'off';
            app.DetectApneasShortcut.Text = 'Detect Apneas';
            app.LoadSignalShortcut = uibutton(app.apneaDetectorWindow, 'push');
            app.LoadSignalShortcut.ButtonPushedFcn = createCallbackFcn(app, @openFile, true);
            app.LoadSignalShortcut.Text = 'Load Signal';
            % Show the figure after all components are created
            app.apneaDetectorWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apneaDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.apneaDetectorWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.apneaDetectorWindow)
        end
    end
end